classdef iClip < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GOButton           matlab.ui.control.Button
        nEditField         matlab.ui.control.NumericEditField
        nEditFieldLabel    matlab.ui.control.Label
        ClippingLamp       matlab.ui.control.Lamp
        ClippingLampLabel  matlab.ui.control.Label
        hEditField         matlab.ui.control.NumericEditField
        hEditFieldLabel    matlab.ui.control.Label
        EditField          matlab.ui.control.NumericEditField
        EditFieldLabel     matlab.ui.control.Label
        CannulaLabel       matlab.ui.control.Label
        ObjectiveLabel     matlab.ui.control.Label
        WDEditField        matlab.ui.control.NumericEditField
        WDEditFieldLabel   matlab.ui.control.Label
        NAEditField        matlab.ui.control.NumericEditField
        NAEditFieldLabel   matlab.ui.control.Label
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            D= ((2*app.WDEditField.Value*app.NAEditField.Value)/app.nEditField.Value);
            starting_point = [0,1];
            T = [starting_point;starting_point(1)+D,1;((starting_point(1)+D - starting_point(1))/2)+starting_point(1),starting_point(2)-app.WDEditField.Value];
            T = delaunayTriangulation(T);
            triplot(T,'Parent',app.UIAxes);
            drawnow;
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            UIAxesButtonDown(app,event)
            UIAxes_2ButtonDown(app,event)
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            D= ((2*app.WDEditField.Value*app.NAEditField.Value)/app.nEditField.Value);
            centerX = 0.5;
            centerY = 0.5;
            th = 0:pi/50:2*pi;
            xunit = D/2 .* cos(th) + centerX;
            yunit = D/2 .* sin(th) + centerY;
            plot(app.UIAxes_2,xunit, yunit);
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Front View')
            xlabel(app.UIAxes, 'Lateral Movement')
            ylabel(app.UIAxes, 'Vertical Movement')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Top View')
            xlabel(app.UIAxes_2, 'Forward Movement')
            ylabel(app.UIAxes_2, 'Lateral Movement')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.NAEditFieldLabel = uilabel(app.UIFigure);
            app.NAEditFieldLabel.Text = 'NA';
            app.NAEditField = uieditfield(app.UIFigure, 'numeric');
            app.NAEditField.Tag = 'NA';
            app.NAEditField.Value = 0.8;
            app.WDEditFieldLabel = uilabel(app.UIFigure);
            app.WDEditFieldLabel.Text = 'WD';
            app.WDEditField = uieditfield(app.UIFigure, 'numeric');
            app.WDEditField.Tag = 'WD';
            app.WDEditField.Value = 3;
            app.ObjectiveLabel = uilabel(app.UIFigure);
            app.ObjectiveLabel.Text = 'Objective:';
            app.CannulaLabel = uilabel(app.UIFigure);
            app.CannulaLabel.Text = 'Cannula:';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = {'⌀'; ''};
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 3;
            app.hEditFieldLabel = uilabel(app.UIFigure);
            app.hEditFieldLabel.Text = 'h';
            app.hEditField = uieditfield(app.UIFigure, 'numeric');
            app.hEditField.Value = 0.5;
            app.ClippingLampLabel = uilabel(app.UIFigure);
            app.ClippingLampLabel.Text = 'Clipping?';
            app.ClippingLamp = uilamp(app.UIFigure);
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.UIFigure, 'numeric');
            app.nEditField.Value = 1.33;
            app.GOButton = uibutton(app.UIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = iClip

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end